classdef scgbox_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.StateButton
        Button_2     matlab.ui.control.Button
        Button_8     matlab.ui.control.StateButton
        Button_6     matlab.ui.control.StateButton
        TextArea     matlab.ui.control.TextArea
        DropDown     matlab.ui.control.DropDown
        Label        matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        ContextMenu  matlab.ui.container.ContextMenu
        font12Menu   matlab.ui.container.Menu
        fontMenu     matlab.ui.container.Menu
        fontMenu_2   matlab.ui.container.Menu
    end


    properties (Access = private)
        s % serial对象
        u % udp对象
        sig4plot % 示波器信号段落
        sig % 采集的信号
        fighandle
        plotflag
        currline
    end

    methods (Access = private)

        function readfromserial(app,~,~)
            app.TextArea.Value=[app.TextArea.Value;{char(readline(app.s))}];
            scroll(app.TextArea,"bottom");
        end

        function readfromudp(app,~,~)
            tmp = double(split(readline(app.u),','));
            app.sig(app.currline,:)=tmp';
            app.currline=app.currline+1;
            app.plotflag=app.plotflag+1;
            if (mod(app.plotflag,3)==0)
                app.sig4plot(1)=[];
                app.sig4plot=[app.sig4plot,tmp(3)];
            end
            if (app.plotflag>33)
                set(app.fighandle,'YData',app.sig4plot);
                drawnow limitrate;
                app.plotflag=0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown.Items=serialportlist;
            app.sig4plot=zeros(1,1000*10);
            app.fighandle=plot(app.UIAxes,app.sig4plot);
            app.plotflag=0;
            app.currline=1;
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            app.DropDown.Items=serialportlist;
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Value;
            if (value)
                app.Button_6.Text="断开设备";
                app.Button_2.Enable="on";
                app.Button_3.Enable="on";
                app.Button_4.Enable="on";
                app.Button_5.Enable="on";
                app.DropDown.Enable="off";
                app.s = serialport(app.DropDown.Value,115200);
                configureCallback(app.s,"terminator",@app.readfromserial);
                app.TextArea.Value={[char(datetime),' start']};
                write(app.s,'?',"char");
                write(app.s,'b',"char");
            else
                configureCallback(app.s,"off");
                app.s=[];
                app.Button_6.Text="连接设备";
                app.Button_2.Enable="off";
                app.Button_3.Enable="off";
                app.Button_4.Enable="off";
                app.Button_5.Enable="off";
                app.DropDown.Enable="on";
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            write(app.s,'r',"char");
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            write(app.s,'b',"char");
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            write(app.s,'?',"char");
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            if(value)
                app.Button_3.Text="停止采集";
                write(app.s,'s',"char");
            else
                app.Button_3.Text="开始采集";
                write(app.s,'e',"char");
            end
        end

        % Menu selected function: font12Menu
        function font12MenuSelected(app, event)
            app.TextArea.FontSize=12;
        end

        % Menu selected function: fontMenu
        function fontMenuSelected(app, event)
            app.TextArea.FontSize=app.TextArea.FontSize+1;
        end

        % Menu selected function: fontMenu_2
        function fontMenu_2Selected(app, event)
            app.TextArea.FontSize=app.TextArea.FontSize-1;
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            value = app.Button_8.Value;
            if(value)
                app.Button_8.Text="停止接收";
                app.currline=1;
                app.sig=zeros([1200*60*3,3]);
                app.u = udpport("LocalPort",3333);
                configureCallback(app.u,"terminator",@app.readfromudp);
            else
                configureCallback(app.u,"off");
                app.u=[];
                app.Button_8.Text="信号接收";
                if(app.currline>1200*60*30)
                    sigtable=app.sig;
                else
                    sigtable=app.sig(1:app.currline-1,:);
                end
                scg=sigtable(:,3);
                ecg=sigtable(:,2);
                tstp=sigtable(:,1);
                tstp=tstp-tstp(1);
                tstp=tstp/1000000;
                sigtable=timetable(seconds(tstp),(scg-mean(scg))/16393,ecg-mean(ecg),'VariableNames',{'scg/g','ecg/?'});
                sigmat=[tstp,scg,ecg];
                if(~isfolder(".\sigs\txt"))
                    mkdir(".\sigs\txt")
                end
                if(~isfolder(".\sigs\mat"))
                    mkdir(".\sigs\mat")
                end
                save(replace(".\sigs\mat\"+string(datetime)+"_sig.mat",[" ",":"],"_"),"sigtable","sigmat");
                save(replace(".\sigs\txt\"+string(datetime)+"_sig.txt",[" ",":"],"_"),"sigmat",'-ascii','-double','-tabs');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '设备选择';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'选项 1', '选项 2'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = '选项 1';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {' '};
            app.Button_6 = uibutton(app.GridLayout, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '连接设备';
            app.Button_8 = uibutton(app.GridLayout, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '信号接收';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '重启';
            app.Button_3 = uibutton(app.GridLayout, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '开始采集';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '电池查询';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '系统信息';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.font12Menu = uimenu(app.ContextMenu);
            app.font12Menu.MenuSelectedFcn = createCallbackFcn(app, @font12MenuSelected, true);
            app.font12Menu.Text = 'font12';
            app.fontMenu = uimenu(app.ContextMenu);
            app.fontMenu.MenuSelectedFcn = createCallbackFcn(app, @fontMenuSelected, true);
            app.fontMenu.Text = 'font+';
            app.fontMenu_2 = uimenu(app.ContextMenu);
            app.fontMenu_2.MenuSelectedFcn = createCallbackFcn(app, @fontMenu_2Selected, true);
            app.fontMenu_2.Text = 'font-';
            % Assign app.ContextMenu
            app.TextArea.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgbox_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end